classdef bwgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageBinarizerUIFigure      matlab.ui.Figure
        GenerateBWButton            matlab.ui.control.Button
        GuassianfilterSpinner       matlab.ui.control.Spinner
        GuassianfilterSpinnerLabel  matlab.ui.control.Label
        GenerateParaButton          matlab.ui.control.Button
        AreaopensizeSpinner         matlab.ui.control.Spinner
        AreaopensizeSpinnerLabel    matlab.ui.control.Label
        maxSpinner                  matlab.ui.control.Spinner
        maxSpinnerLabel             matlab.ui.control.Label
        FrameSpinner                matlab.ui.control.Spinner
        PreviewVideoButton          matlab.ui.control.Button
        SensitivitySlider           matlab.ui.control.Slider
        SensitivitySliderLabel      matlab.ui.control.Label
        minSpinner                  matlab.ui.control.Spinner
        minSpinnerLabel             matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        PreviewButton               matlab.ui.control.Button
        BinarizeCheckBox            matlab.ui.control.CheckBox
        BandpassCheckBox            matlab.ui.control.CheckBox
        InvertCheckBox              matlab.ui.control.CheckBox
        FrameLabel                  matlab.ui.control.Label
        ImageAxes2                  matlab.ui.control.UIAxes
        ImageAxes1                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imgStack % Raw image
        imgOriginal
        bwPara
    end

    methods (Access = private)

        function img = generatebw(app)
            img = app.imgOriginal;
            if app.bwPara.invert
                img = imcomplement(img);
            end

            if app.bwPara.isbp
                [img, ~] = bandpassfilt(img, app.bwPara.bpmin, app.bwPara.bpmax);
            end
            if app.bwPara.gaussSigma ~= 0
                img = imgaussfilt(img, app.bwPara.gaussSigma);
            end

            if app.bwPara.binarize
                img = imbinarize(img, 'adaptive', 'ForegroundPolarity','bright', 'Sensitivity',app.bwPara.sensitivity);
                img = bwareaopen(img, app.bwPara.areaopenSize);
            end
        end

        function bw = generatebwstack(app, nImg)
            f = waitbar(0,'0.0%','Name','Binarzing images...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            setappdata(f,'canceling',0);

            bw = false([size(app.imgStack, [1 2]), nImg]);

            for iImg = 1:nImg
                % Check for clicked Cancel button
                if getappdata(f,'canceling')
                    break
                end
                bw(:,:,iImg) = getbw(app.imgStack(:,:,iImg), app.bwPara);
                waitbar(iImg/nImg,f,sprintf('%4.1f%%',iImg/nImg*100));
            end
            delete(f);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)
            % move gui to the screen center
            app.ImageBinarizerUIFigure.Visible = 'off';
            movegui(app.ImageBinarizerUIFigure,"center")
            app.ImageBinarizerUIFigure.Visible = 'on';

            app.FrameSlider.Value = 1;
            %  default value
            app.imgStack = img;
            app.imgOriginal = app.imgStack(:,:,app.FrameSlider.Value);
            %  bwPara
            app.bwPara.bpmax = 6;
            app.bwPara.bpmin = 1;
            app.bwPara.gaussSigma = 1;
            app.bwPara.areaopenSize = 8;
            app.bwPara.isbp = true;
            app.bwPara.sensitivity = 0.5;
            app.bwPara.invert = false;
            app.bwPara.binarize = true;

            if size(img,3) == 1
                app.FrameSlider.Enable = false;
                app.PreviewVideoButton.Enable = false;
            else
                app.FrameSlider.Limits = [1, size(img,3)];
                app.FrameSlider.MajorTicks = floor(linspace(1, size(img,3), 5));
            end

            app.FrameSlider.Value = 1;
            app.FrameSpinner.Value = 1;

            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            disableDefaultInteractivity(app.ImageAxes1);  % Fix bug of fleur pointer outside of roi. Fix at R2022b according to Mathwork.
            disableDefaultInteractivity(app.ImageAxes2);

            imshow(mat2gray(app.imgOriginal), 'Parent', app.ImageAxes1);
        end

        % Value changed function: maxSpinner
        function maxSpinnerValueChanged(app, event)
            app.bwPara.bpmax = app.maxSpinner.Value;
        end

        % Value changed function: minSpinner
        function minSpinnerValueChanged(app, event)
            app.bwPara.bpmin = app.minSpinner.Value;
        end

        % Value changed function: GuassianfilterSpinner
        function GuassianfilterSpinnerValueChanged(app, event)
            app.bwPara.gaussSigma = app.GuassianfilterSpinner.Value;
        end

        % Value changed function: AreaopensizeSpinner
        function AreaopensizeSpinnerValueChanged(app, event)
            app.bwPara.areaopenSize = app.AreaopensizeSpinner.Value;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            bw1 = generatebw(app);
            imshow(mat2gray(app.imgOriginal), 'Parent', app.ImageAxes1);
            imshow(bw1, 'Parent', app.ImageAxes2);
            waitbar(1,f,'Finishing');
            close(f);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            app.FrameSpinner.Value = app.FrameSlider.Value;
            app.imgOriginal = app.imgStack(:,:,app.FrameSpinner.Value);
        end

        % Button pushed function: GenerateParaButton
        function GenerateParaButtonPushed(app, event)
            %% Save [bwPara] in the unrepeatable varname
            rawname = 'bwPara';
            iName = 0;
            savename = rawname;
            varname = evalin('base','whos'); %or 'base'
            varlist = [varname(:).name];
            while ismember(savename, varlist)
                iName = iName + 1;
                num = sprintf('%02d', iName);
                savename = [rawname num];
            end
            assignin("base", savename, app.bwPara);
            disp(['-------Binarized parameters saved in-------' savename]);
            %% Save [bwPara] in [bwPara]
            %             assignin("base", "bwPara", app.bwPara);
        end

        % Button pushed function: GenerateBWButton
        function GenerateBWButtonPushed(app, event)
            nImg = size(app.imgStack, 3);
            bw = generatebwstack(app, nImg);
            %% Save [bw] in the unrepeatable varname
            rawname = 'bw';
            iName = 0;
            savename = rawname;
            varname = evalin('base','whos'); %or 'base'
            varlist = [varname(:).name];
            while ismember(savename, varlist)
                iName = iName + 1;
                num = sprintf('%02d', iName);
                savename = [rawname num];
            end
            assignin("base", savename, bw);
            disp(['-------Binarized images saved in-------' savename]);
            %%  Save [bw] in [bwFromGUI]
            %             assignin("base", "bwFromGUI", bw);
            %             delete(f);
            %             disp('-------Binarized images saved in------- bwFromGUI');
        end

        % Button pushed function: PreviewVideoButton
        function PreviewVideoButtonPushed(app, event)
            bw = generatebwstack(app, 10);
            implay(bw);
        end

        % Value changed function: BandpassCheckBox
        function BandpassCheckBoxValueChanged(app, event)
            app.bwPara.isbp = app.BandpassCheckBox.Value;
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            app.bwPara.sensitivity = app.SensitivitySlider.Value;
        end

        % Value changed function: InvertCheckBox
        function InvertCheckBoxValueChanged(app, event)
            app.bwPara.invert = app.InvertCheckBox.Value;
        end

        % Value changed function: BinarizeCheckBox
        function BinarizeCheckBoxValueChanged(app, event)
            app.bwPara.binarize = app.BinarizeCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageBinarizerUIFigure = uifigure('Visible', 'off');
            app.ImageBinarizerUIFigure.Name = 'Image Binarizer';
            app.ImageBinarizerUIFigure.Icon = fullfile(pathToMLAPP, 'logo.jpg');
            app.ImageAxes1 = uiaxes(app.ImageBinarizerUIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.ImageBinarizerUIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.FrameLabel = uilabel(app.ImageBinarizerUIFigure);
            app.FrameLabel.Text = 'Frame';
            app.InvertCheckBox = uicheckbox(app.ImageBinarizerUIFigure);
            app.InvertCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertCheckBoxValueChanged, true);
            app.InvertCheckBox.Text = 'Invert';
            app.BandpassCheckBox = uicheckbox(app.ImageBinarizerUIFigure);
            app.BandpassCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandpassCheckBoxValueChanged, true);
            app.BandpassCheckBox.Text = 'Bandpass';
            app.BandpassCheckBox.Value = true;
            app.BinarizeCheckBox = uicheckbox(app.ImageBinarizerUIFigure);
            app.BinarizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinarizeCheckBoxValueChanged, true);
            app.BinarizeCheckBox.Text = 'Binarize';
            app.BinarizeCheckBox.Value = true;
            app.PreviewButton = uibutton(app.ImageBinarizerUIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.FrameSlider = uislider(app.ImageBinarizerUIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.minSpinnerLabel = uilabel(app.ImageBinarizerUIFigure);
            app.minSpinnerLabel.Text = 'min';
            app.minSpinner = uispinner(app.ImageBinarizerUIFigure);
            app.minSpinner.ValueChangedFcn = createCallbackFcn(app, @minSpinnerValueChanged, true);
            app.minSpinner.Value = 1;
            app.SensitivitySliderLabel = uilabel(app.ImageBinarizerUIFigure);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.ImageBinarizerUIFigure);
            app.SensitivitySlider.Limits = [0 1];
            app.SensitivitySlider.MajorTicks = [0 0.5 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 0.5;
            app.PreviewVideoButton = uibutton(app.ImageBinarizerUIFigure, 'push');
            app.PreviewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewVideoButtonPushed, true);
            app.PreviewVideoButton.Text = 'Preview Video';
            app.FrameSpinner = uispinner(app.ImageBinarizerUIFigure);
            app.FrameSpinner.Value = 1;
            app.maxSpinnerLabel = uilabel(app.ImageBinarizerUIFigure);
            app.maxSpinnerLabel.Text = 'max';
            app.maxSpinner = uispinner(app.ImageBinarizerUIFigure);
            app.maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxSpinnerValueChanged, true);
            app.maxSpinner.Value = 6;
            app.AreaopensizeSpinnerLabel = uilabel(app.ImageBinarizerUIFigure);
            app.AreaopensizeSpinnerLabel.Text = 'Areaopen size';
            app.AreaopensizeSpinner = uispinner(app.ImageBinarizerUIFigure);
            app.AreaopensizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AreaopensizeSpinnerValueChanged, true);
            app.AreaopensizeSpinner.Value = 8;
            app.GenerateParaButton = uibutton(app.ImageBinarizerUIFigure, 'push');
            app.GenerateParaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateParaButtonPushed, true);
            app.GenerateParaButton.Text = 'Generate Para';
            app.GuassianfilterSpinnerLabel = uilabel(app.ImageBinarizerUIFigure);
            app.GuassianfilterSpinnerLabel.Text = 'Guassian filter';
            app.GuassianfilterSpinner = uispinner(app.ImageBinarizerUIFigure);
            app.GuassianfilterSpinner.ValueChangedFcn = createCallbackFcn(app, @GuassianfilterSpinnerValueChanged, true);
            app.GuassianfilterSpinner.Value = 1;
            app.GenerateBWButton = uibutton(app.ImageBinarizerUIFigure, 'push');
            app.GenerateBWButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBWButtonPushed, true);
            app.GenerateBWButton.Text = 'Generate BW';
            % Show the figure after all components are created
            app.ImageBinarizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bwgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageBinarizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageBinarizerUIFigure)
        end
    end
end