classdef bwgui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AreaopensizeSpinner         matlab.ui.control.Spinner
        AreaopensizeSpinnerLabel    matlab.ui.control.Label
        FrameLabel                  matlab.ui.control.Label
        FrameSlider                 matlab.ui.control.Slider
        FrameSpinner                matlab.ui.control.Spinner
        GuassianfilterSpinner       matlab.ui.control.Spinner
        GuassianfilterSpinnerLabel  matlab.ui.control.Label
        BandpassmaxSpinner          matlab.ui.control.Spinner
        BandpassmaxSpinnerLabel     matlab.ui.control.Label
        BandpassminSpinner          matlab.ui.control.Spinner
        ApplyButton                 matlab.ui.control.Button
        BandpassminSpinnerLabel     matlab.ui.control.Label
        ImageAxes2                  matlab.ui.control.UIAxes
        ImageAxes1                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imgStack % Raw image
        imgbw
        imgOriginal
        frame
        bpmin
        bpmax
        gaussSigma
        areaopenSize
    end

    methods (Access = private)

        function bw = getbw(app)
            img = app.imgOriginal;
            [bpimg, ~] = bandpassfilt(img, app.bpmin, app.bpmax);
            bw = imbinarize(imgaussfilt(bpimg, app.gaussSigma), 'adaptive');
            bw = bwareaopen(bw, app.areaopenSize);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)
            app.FrameSlider.Value = 1;
            app.imgStack = img;
            app.imgOriginal = app.imgStack(:,:,app.FrameSlider.Value);
            %  default value
            app.bpmax = 6;
            app.bpmin = 1;
            app.gaussSigma = 1;
            app.FrameSlider.Limits = [1, size(img,3)];
            app.FrameSlider.Value = 1;
            app.FrameSpinner.Value = 1;
            app.areaopenSize = 8;
            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            disableDefaultInteractivity(app.ImageAxes1);  % Fix bug of fleur pointer outside of roi. Fix at R2022b according to Mathwork.
            disableDefaultInteractivity(app.ImageAxes2);
        
            imshow(imadjust(app.imgOriginal), 'Parent', app.ImageAxes1);
        end

        % Value changed function: BandpassmaxSpinner
        function BandpassmaxSpinnerValueChanged(app, event)
            app.bpmax = app.BandpassmaxSpinner.Value;
        end

        % Value changed function: BandpassminSpinner
        function BandpassminSpinnerValueChanged(app, event)
            app.bpmin = app.BandpassminSpinner.Value;
        end

        % Value changed function: GuassianfilterSpinner
        function GuassianfilterSpinnerValueChanged(app, event)
            app.gaussSigma = app.GuassianfilterSpinner.Value;
        end

        % Value changed function: AreaopensizeSpinner
        function AreaopensizeSpinnerValueChanged(app, event)
            app.areaopenSize = app.AreaopensizeSpinner.Value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            bw1 = getbw(app);
            imshow(imadjust(app.imgOriginal), 'Parent', app.ImageAxes1);
            imshow(bw1, 'Parent', app.ImageAxes2);
            waitbar(1,f,'Finishing');
            close(f);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            app.FrameSlider.Value = round(app.FrameSlider.Value);
            app.FrameSpinner.Value = app.FrameSlider.Value;
            app.imgOriginal = app.imgStack(:,:,app.FrameSpinner.Value);
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            app.FrameSlider.Value = app.FrameSpinner.Value;
            app.imgOriginal = app.imgStack(:,:,app.FrameSpinner.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.BandpassminSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassminSpinnerLabel.Text = 'Bandpass min';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BandpassminSpinner = uispinner(app.UIFigure);
            app.BandpassminSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassminSpinnerValueChanged, true);
            app.BandpassminSpinner.Value = 1;
            app.BandpassmaxSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassmaxSpinnerLabel.Text = 'Bandpass max';
            app.BandpassmaxSpinner = uispinner(app.UIFigure);
            app.BandpassmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassmaxSpinnerValueChanged, true);
            app.BandpassmaxSpinner.Value = 6;
            app.GuassianfilterSpinnerLabel = uilabel(app.UIFigure);
            app.GuassianfilterSpinnerLabel.Text = 'Guassian filter';
            app.GuassianfilterSpinner = uispinner(app.UIFigure);
            app.GuassianfilterSpinner.ValueChangedFcn = createCallbackFcn(app, @GuassianfilterSpinnerValueChanged, true);
            app.GuassianfilterSpinner.Value = 1;
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Value = 1;
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.AreaopensizeSpinnerLabel = uilabel(app.UIFigure);
            app.AreaopensizeSpinnerLabel.Text = 'Areaopen size';
            app.AreaopensizeSpinner = uispinner(app.UIFigure);
            app.AreaopensizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AreaopensizeSpinnerValueChanged, true);
            app.AreaopensizeSpinner.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bwgui1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end